classdef AAPTS_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        ArduinoTestTab      matlab.ui.container.Tab
        Gauge               matlab.ui.control.LinearGauge
        StartButton         matlab.ui.control.Button
        StatusLabel         matlab.ui.control.Label
        Label               matlab.ui.control.Label
        DistanceReadLabel   matlab.ui.control.Label
        CarStatisticsLabel  matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        CarParkingSlotsTab  matlab.ui.container.Tab
        SetStatusLabel_8    matlab.ui.control.Label
        SetStatusLabel_7    matlab.ui.control.Label
        SetStatusLabel_6    matlab.ui.control.Label
        SetStatusLabel_5    matlab.ui.control.Label
        SetStatusLabel_4    matlab.ui.control.Label
        SetStatusLabel_3    matlab.ui.control.Label
        SetStatusLabel_2    matlab.ui.control.Label
        SetStatusLabel      matlab.ui.control.Label
        OccupiedLabel       matlab.ui.control.Label
        AvailableLabel      matlab.ui.control.Label
        TotalSlots8Label    matlab.ui.control.Label
        Slot8Button         matlab.ui.control.Button
        Slot7Button_2       matlab.ui.control.Button
        Slot6Button_2       matlab.ui.control.Button
        Slot5Button_2       matlab.ui.control.Button
        Slot4Button         matlab.ui.control.Button
        Slot3Button         matlab.ui.control.Button
        Slot2Button         matlab.ui.control.Button
        Lamp2_9             matlab.ui.control.Lamp
        Lamp2_8             matlab.ui.control.Lamp
        Lamp2_7             matlab.ui.control.Lamp
        Lamp2_6             matlab.ui.control.Lamp
        Slot1Button         matlab.ui.control.Button
        Lamp2_5             matlab.ui.control.Lamp
        Lamp2_4             matlab.ui.control.Lamp
        Lamp2_3             matlab.ui.control.Lamp
        Lamp2               matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        ultrasonicObj % Description
        a % Description
        isLooping % Description
    end
    
    methods (Access = public)
        
        function updateDistance(app, lamp, statusLabel)
            distance = 100.0*readDistance(app.ultrasonicObj)
            app.DistanceReadLabel.Text = "Distance: "+string(distance);
            app.Gauge.Value = distance;
            if distance <= 5
                lamp.Color = "red";
                statusLabel.Text = "Status: Occupied";
                
            else
                lamp.Color = "green";
                statusLabel.Text = "Status: Available";
            end
            drawnow
            pause(0.5)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all;
            app.a = arduino('COM6', 'Uno', 'Libraries', 'Ultrasonic');

            app.ultrasonicObj = ultrasonic(app.a,'D6','D7');
            
            app.isLooping = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Change state of isLooping
            app.isLooping = ~app.isLooping;

            % Change button text
            if app.isLooping
                app.StartButton.Text = "Stop";
            else
                app.StartButton.Text = "Start";
            end

            % :)
            while app.isLooping
                updateDistance(app, app.Lamp, app.StatusLabel);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArduinoTestTab = uitab(app.TabGroup);
            app.ArduinoTestTab.Title = 'Arduino Test';
            app.Lamp = uilamp(app.ArduinoTestTab);
            app.CarStatisticsLabel = uilabel(app.ArduinoTestTab);
            app.CarStatisticsLabel.Text = 'Car Statistics';
            app.DistanceReadLabel = uilabel(app.ArduinoTestTab);
            app.DistanceReadLabel.Text = 'Distance Read: ';
            app.Label = uilabel(app.ArduinoTestTab);
            app.Label.Text = '';
            app.StatusLabel = uilabel(app.ArduinoTestTab);
            app.StatusLabel.Text = 'Status: ';
            app.StartButton = uibutton(app.ArduinoTestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Gauge = uigauge(app.ArduinoTestTab, 'linear');
            app.Gauge.Limits = [0 40];
            app.CarParkingSlotsTab = uitab(app.TabGroup);
            app.CarParkingSlotsTab.Title = 'Car Parking Slots';
            app.Lamp2 = uilamp(app.CarParkingSlotsTab);
            app.Lamp2_3 = uilamp(app.CarParkingSlotsTab);
            app.Lamp2_4 = uilamp(app.CarParkingSlotsTab);
            app.Lamp2_5 = uilamp(app.CarParkingSlotsTab);
            app.Slot1Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot1Button.Text = 'Slot 1';
            app.Lamp2_6 = uilamp(app.CarParkingSlotsTab);
            app.Lamp2_7 = uilamp(app.CarParkingSlotsTab);
            app.Lamp2_8 = uilamp(app.CarParkingSlotsTab);
            app.Lamp2_9 = uilamp(app.CarParkingSlotsTab);
            app.Slot2Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot2Button.Text = 'Slot 2';
            app.Slot3Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot3Button.Text = 'Slot 3';
            app.Slot4Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot4Button.Text = 'Slot 4';
            app.Slot5Button_2 = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot5Button_2.Text = 'Slot 5';
            app.Slot6Button_2 = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot6Button_2.Text = 'Slot 6';
            app.Slot7Button_2 = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot7Button_2.Text = 'Slot 7';
            app.Slot8Button = uibutton(app.CarParkingSlotsTab, 'push');
            app.Slot8Button.Text = 'Slot 8';
            app.TotalSlots8Label = uilabel(app.CarParkingSlotsTab);
            app.TotalSlots8Label.Text = 'Total Slots: 8';
            app.AvailableLabel = uilabel(app.CarParkingSlotsTab);
            app.AvailableLabel.Text = 'Available: ';
            app.OccupiedLabel = uilabel(app.CarParkingSlotsTab);
            app.OccupiedLabel.Text = 'Occupied: ';
            app.SetStatusLabel = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel.Text = '#1: Set Status';
            app.SetStatusLabel_2 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_2.Text = '#5: Set Status';
            app.SetStatusLabel_3 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_3.Text = '#2: Set Status';
            app.SetStatusLabel_4 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_4.Text = '#6: Set Status';
            app.SetStatusLabel_5 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_5.Text = '#3: Set Status';
            app.SetStatusLabel_6 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_6.Text = '#7: Set Status';
            app.SetStatusLabel_7 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_7.Text = '#4: Set Status';
            app.SetStatusLabel_8 = uilabel(app.CarParkingSlotsTab);
            app.SetStatusLabel_8.Text = '#8: Set Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AAPTS_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end